classdef BASOOP_viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        EchogramTab                matlab.ui.container.Tab
        ProcImage                  matlab.ui.control.Image
        BrowseImageLocatoin        matlab.ui.control.Button
        RawImage                   matlab.ui.control.Image
        ImageLocation              matlab.ui.control.EditField
        OpenEvFileRaw              matlab.ui.control.Button
        Auto                       matlab.ui.control.Button
        Manual                     matlab.ui.control.Button
        BackButton                 matlab.ui.control.Button
        ForwardButton              matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        ImageNameLabel             matlab.ui.control.Label
        FiletypeLabel              matlab.ui.control.Label
        Setfiletype                matlab.ui.control.DropDown
        EditFieldLabel             matlab.ui.control.Label
        Fileindex                  matlab.ui.control.NumericEditField
        PlaybackspeedLabel         matlab.ui.control.Label
        Playbackspeed              matlab.ui.control.Slider
        SetfrequencyDropDownLabel  matlab.ui.control.Label
        SetfrequencyDropDown       matlab.ui.control.DropDown
        ScrollimagesSliderLabel    matlab.ui.control.Label
        ScrollimagesSlider         matlab.ui.control.Slider
        CruiseTrackTab             matlab.ui.container.Tab
        CruiseTrackFig             matlab.ui.control.UIAxes
        SetstartpointLabel         matlab.ui.control.Label
        StartTime                  matlab.ui.control.Slider
        SetendpointSliderLabel     matlab.ui.control.Label
        SetendpointSlider          matlab.ui.control.Slider
    end

    
    methods (Access = private)        
        function results = loadcruisetrack(app)            
            d = app.ImageLocation.Value;
            filetype = app.Setfiletype.Value;
            % list raw and proc files by file type and frequency
            freq = app.SetfrequencyDropDown.Value;
            freq = cell2mat(regexp(freq,'\d*','Match'));                        
            rawfiles = ls([d '\raw\*' freq  'k*.' filetype]);
                                            
            start_idx = round(app.StartTime.Value(1));   
            end_idx = round(app.SetendpointSlider.Value(1));  
            
            if start_idx < end_idx % has to be                      
                sep = strfind(rawfiles(1,:),'_');
                lat = rawfiles(start_idx:end_idx,sep(3)+1:sep(4));
                lat = rawfiles(start_idx:end_idx,sep(3)+1:sep(4)-1);
                lon = rawfiles(start_idx:end_idx,sep(4)+1:sep(5)-1);
                if isequal(lat(end),'S')
                    lat = -str2num(lat(:,1:end-1));
                else
                    lat = -str2num(lat(:,1:end-1));
                end            
                lon = str2num(lon(:,1:end-1));                       
                p1 = plot(app.CruiseTrackFig, lon,lat)  ;          
                hold(app.CruiseTrackFig,'on')
                p1 = plot(app.CruiseTrackFig, lon,lat,'o');
                %i = round(app.ScrollimagesSlider.Value);            
                %i = start_idx;
                p1= plot(app.CruiseTrackFig, lon(1),lat(1),'o');
                p1.MarkerSize = 18; p1.MarkerFaceColor = [0 1 0];  
                
                p1= plot(app.CruiseTrackFig, lon(end),lat(end),'o');              
                p1.MarkerSize = 18; p1.MarkerFaceColor = [1 0 0]     ;                
                current_idx = round(app.ScrollimagesSlider.Value);                   
                if current_idx >=start_idx & current_idx<=end_idx                    
                    current_idx = round(app.ScrollimagesSlider.Value) - start_idx +1;                                        
                    p1 = plot(app.CruiseTrackFig,lon(current_idx),lat(current_idx),'o');
                    p1.MarkerSize = 18; p1.MarkerFaceColor = [0 1 1];  
                end                
                hold(app.CruiseTrackFig,'off')                
            end                   
        end
        function results = image_info(app)
                d = app.ImageLocation.Value;
                filetype = app.Setfiletype.Value;
                % list raw and proc files by file type and frequency
                freq = app.SetfrequencyDropDown.Value;
                freq = cell2mat(regexp(freq,'\d*','Match'));                        
                rawfiles = ls([d '\raw\*' freq  'k*.' filetype]);  
                procfiles = ls([d '\proc\*' freq  'k*.' filetype]);                             
                evfiles = rawfiles(:,1:17);
                evfilelist = unique(evfiles,'rows');
                [p,~] =  size(evfilelist);
                num_evfiles = p;
                
                i= app.Fileindex.Value - 1;
                if i>=1                
                    app.RawImage.ImageSource = [d '\raw\' rawfiles(i,:)];                
                    app.ProcImage.ImageSource = [d '\proc\' procfiles(i,:)];
                    app.Fileindex.Value =i; 
                
                    evfile = rawfiles(i,1:17);      
                    image_date = rawfiles(i,19:33);
                    which_ev = find(~cellfun('isempty',strfind(cellstr(evfilelist),evfile)),1);                
                    app.ImageNameLabel.Text = sprintf('%s.ev. File %d of %d. Image time: %s',evfile,which_ev,num_evfiles,image_date); 
                
                else
                    fprintf('at the start of the image set\n');
                end                                  
        end
                     
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                drawnow;
                app.UIFigure.WindowState = 'maximized';   


                app.ImageLocation.Value = pwd; % set image directory to current location
                d = app.ImageLocation.Value;
                filetype = app.Setfiletype.Value;
                % list raw and proc files by file type and frequency
                freq = app.SetfrequencyDropDown.Value;
                freq = cell2mat(regexp(freq,'\d*','Match'));                        
                rawfiles = ls([d '\raw\*' freq  'k*.' filetype]);
                procfiles = ls([d '\proc\*' freq 'k*.' filetype]);
                
                % first raw file                  
                if isempty(rawfiles)
                    %msgbox(sprintf('Browse to set image directory\n'))
                    imagedir = uigetdir([],'Browse to set image directory');
                    app.ImageLocation.Value=imagedir;
                    d = app.ImageLocation.Value;
                    rawfiles = ls([d '\raw\*' freq  'k*.' filetype]);
                    procfiles = ls([d '\proc\*' freq 'k*.' filetype]);                    
                end                              
                app.RawImage.ImageSource = [d '\raw\' rawfiles(1,:)];
                app.ProcImage.ImageSource = [d '\proc\' procfiles(1,:)];                                       
                
                [m,~]=size(rawfiles);
                app.ScrollimagesSlider.Limits = [1 m];   
                app.StartTime.Limits = [1 m];      
                app.SetendpointSlider.Limits = [1 m];
                app.SetendpointSlider.Value = m;                
                loadcruisetrack(app)                 
                
        end

        % Button pushed function: BrowseImageLocatoin
        function BrowseImageLocatoinButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            d = uigetdir;         
            app.ImageLocation.Value = d;
            % load the first image            
            filetype = app.Setfiletype.Value;
            rawfiles = ls([d '\raw\*.' filetype]);
            procfiles = ls([d '\proc\*.' filetype]);
            
            % first raw file  
            app.RawImage.ImageSource = [d '\raw\' rawfiles(1,:)];
            app.ProcImage.ImageSource = [d '\proc\' procfiles(1,:)];
            
        end

        % Callback function
        function AutoButtonPushed(app, event)
            
            d = app.ImageLocation.Value;
            filetype = app.Setfiletype.Value;
            rawfiles = ls([d '\raw\*.' filetype]);
            procfiles = ls([d '\proc\*.' filetype]);
            [m,n]= size(rawfiles);                       
            i=1;
                        
            % set playback speed
            % 100% is flat out
            % 0 % is 2 seconds           
            pause_value = (100 - app.Playbackspeed.Value)/100;
            while i<m 
                %keyboard
                %if isequal(event.EventName,'ButtonPushed')
                %    fprintf('hello')
                %    break
               % end
                app.RawImage.ImageSource = [d '\raw\' rawfiles(i,:)];
                app.ProcImage.ImageSource = [d '\proc\' procfiles(i,:)];
                %pause(pause_value)
                    pause(.2)
                app.Fileindex.Value = i;      
                i=i+1;
            end


        end

        % Button pushed function: OpenEvFileRaw
        function OpenEvFileRawButtonPushed(app, event)
                               
            [imagedir, rawimagename] = fileparts(app.RawImage.ImageSource);                       
            [imagedir, procimagename] = fileparts(app.ProcImage.ImageSource);                       
            rootdir = fileparts(imagedir);                        
            % set ev file with path
                delim = strfind(rawimagename,'_');
                evfile = rawimagename(1:delim(1)-1);                           
                evdir = [fileparts(rootdir) '\Ev_files'];                                         
                evfile = [evfile '.ev'];
                evfilewithpath = fullfile(evdir, evfile);
            % find the variable to view
                delim = strfind(rawimagename,'_');
                rawvar = rawimagename(delim(end)+1:end);
                procvar = procimagename(delim(end)+1:end);
            % find the time of the image
                datetime = rawimagename(delim(1)+1:delim(3)-1 );
                year = str2num(datetime(1:4)); month = str2num(datetime(5:6)); day = str2num(datetime(7:8));
                hour = str2num(datetime(10:11)); minute = str2num(datetime(12:13)); sec = str2num(datetime(14:15));
            % now open the ev file
                EvApp = actxserver('EchoviewCom.EvApplication');
                EvFile = EvApp.OpenFile(evfilewithpath);  
                if isempty(EvFile)
                    msgbox(sprintf('Ev File not found so cannot be opened!, check your directory settings\n'))                    
                else                
                    RawVar = EvFile.Variables.FindByName(rawvar);                
                    ProcVar = EvFile.Variables.FindByName(procvar);                
                    d = COM.date(year,month,day,hour,minute,sec); % date object
                    ProcVar.GoToPing(1, d);                 
                    RawVar.GoToPing(1, d);                    
                    pause
                    saveev = questdlg('Do you want to save the ev file','Save ev?','Yes','No','Yes');
                    if isequal(saveev,'Yes')
                        EvFile.Save;                
                    end 
                    EvFile.Close;
                    EvApp.Quit;
                end
        end

        % Callback function
        function CloseButtonPushed(app, event)
            
            
        end

        % Button pushed function: Auto
        function AutoButtonPushed2(app, event)
            d = app.ImageLocation.Value;
            filetype = app.Setfiletype.Value;
            % list raw and proc files by file type and frequency
            freq = app.SetfrequencyDropDown.Value;
            freq = cell2mat(regexp(freq,'\d*','Match'));                        
            rawfiles = ls([d '\raw\*' freq  'k*.' filetype]);
            procfiles = ls([d '\proc\*' freq  'k*.' filetype]);
            [m,n]= size(rawfiles);                       
                     
            evfiles = rawfiles(:,1:17);
            evfilelist = unique(evfiles,'rows');
            [p,q] =  size(evfilelist);
            num_evfiles = q;
            
            % set playback speed
            % 100% is flat out
            % 0 % is 2 seconds   
            
            i=1;                                         
            while(~isequal(app.Auto.UserData,'Stop'))                                                   
                app.RawImage.ImageSource = [d '\raw\' rawfiles(i,:)];                
                app.ProcImage.ImageSource = [d '\proc\' procfiles(i,:)];
                pause_value = (100 - app.Playbackspeed.Value)/50 +0.05;
                app.ScrollimagesSlider.Value = i;
                pause(pause_value)
                %    pause(.2)
                app.Fileindex.Value = i;      
                if app.Fileindex.Value<m
                    i=i+1;    
                else
                    i=1;
                end       
                % report ev file and time of image         
                image_info(app);                
            end
            app.Auto.UserData = 'Continue';
            loadcruisetrack(app); %display cruise track
        end

        % Button pushed function: Manual
        function ManualButtonPushed(app, event)
            app.Auto.UserData = 'Stop';            
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            
          d = app.ImageLocation.Value;
            filetype = app.Setfiletype.Value;
            % list raw and proc files by file type and frequency
            freq = app.SetfrequencyDropDown.Value;
            freq = cell2mat(regexp(freq,'\d*','Match'));                        
            rawfiles = ls([d '\raw\*' freq  'k*.' filetype]);
            procfiles = ls([d '\proc\*' freq  'k*.' filetype]);
            
            evfiles = rawfiles(:,1:17);
            evfilelist = unique(evfiles,'rows');
            [p,~] =  size(evfilelist);
            num_evfiles =p;
            
            i= app.Fileindex.Value - 1;
            if i>=1                
                app.RawImage.ImageSource = [d '\raw\' rawfiles(i,:)];                
                app.ProcImage.ImageSource = [d '\proc\' procfiles(i,:)];
                app.Fileindex.Value =i; 
                
                evfile = rawfiles(i,1:17);      
                image_date = rawfiles(i,19:33);
                which_ev = find(~cellfun('isempty',strfind(cellstr(evfilelist),evfile)),1);                
                app.ImageNameLabel.Text = sprintf('%s.ev. File %d of %d. Image time: %s',evfile,which_ev,num_evfiles,image_date); 
                app.ScrollimagesSlider.Value = i;
                loadcruisetrack(app)
            else
                fprintf('at the start of the image set\n');
            end            
            
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
                                  
            d = app.ImageLocation.Value;
            filetype = app.Setfiletype.Value;
            %list raw and proc files by file type and frequency
            freq = app.SetfrequencyDropDown.Value;
            freq = cell2mat(regexp(freq,'\d*','Match'));                        
            rawfiles = ls([d '\raw\*' freq  'k*.' filetype]);
            procfiles = ls([d '\proc\*' freq  'k*.' filetype]);   
            
            evfiles = rawfiles(:,1:17);
            evfilelist = unique(evfiles,'rows');
            [p,~] =  size(evfilelist);
            num_evfiles = p;
            [m,n]=size(rawfiles);
            i = app.Fileindex.Value + 1;
            if i<=m                
                app.RawImage.ImageSource = [d '\raw\' rawfiles(i,:)];                
                app.ProcImage.ImageSource = [d '\proc\' procfiles(i,:)];            
                app.Fileindex.Value =i;                               
                
                evfile = rawfiles(i,1:17);      
                image_date = rawfiles(i,19:33);
                which_ev = find(~cellfun('isempty',strfind(cellstr(evfilelist),evfile)),1);                
                app.ImageNameLabel.Text = sprintf('%s.ev. File %d of %d. Image time: %s',evfile,which_ev,num_evfiles,image_date); 
                app.ScrollimagesSlider.Value = i;
                loadcruisetrack(app)
            else
                fprintf('reached the end of the image set\n');
            end            
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            d = app.ImageLocation.Value;
            filetype = app.Setfiletype.Value;
            % list raw and proc files by file type and frequency
            freq = app.SetfrequencyDropDown.Value;
            freq = cell2mat(regexp(freq,'\d*','Match'));                        
            rawfiles = ls([d '\raw\*' freq  'k*.' filetype]);
            procfiles = ls([d '\proc\*' freq  'k*.' filetype]);
            i = 1;
            evfiles = rawfiles(:,1:17);
            evfilelist = unique(evfiles,'rows');
            [p,q] =  size(evfilelist);
            num_evfiles = p;
            
            app.RawImage.ImageSource = [d '\raw\' rawfiles(i,:)];                
            app.ProcImage.ImageSource = [d '\proc\' procfiles(i,:)];
            app.Fileindex.Value=i;
            
            evfile = rawfiles(i,1:17);      
            image_date = rawfiles(i,19:33);
            which_ev = find(~cellfun('isempty',strfind(cellstr(evfilelist),evfile)),1);                
            app.ImageNameLabel.Text = sprintf('%s.ev. File %d of %d. Image time: %s',evfile,which_ev,num_evfiles,image_date); 
            app.ScrollimagesSlider.Value=1;
            loadcruisetrack(app)
            
            
        end

        % Size changed function: CruiseTrackTab
        function CruisetrackButtonPushed(app, event)
            
        end

        % Callback function
        function CruiseTrackTabSizeChanged(app, event)
            position = app.CruiseTrackTab.Position;            
        end

        % Value changed function: ScrollimagesSlider
        function ScrollimagesSliderValueChanged(app, event)
            value = app.ScrollimagesSlider.Value;                                             
            d = app.ImageLocation.Value;
            filetype = app.Setfiletype.Value;            
            freq = app.SetfrequencyDropDown.Value;
            freq = cell2mat(regexp(freq,'\d*','Match'));                        
            rawfiles = ls([d '\raw\*' freq  'k*.' filetype]); 
            procfiles = ls([d '\proc\*' freq  'k*.' filetype]);
            [m,~]=size(rawfiles);            
            i = round(app.ScrollimagesSlider.Value);
            app.RawImage.ImageSource = [d '\raw\' rawfiles(i,:)];                
            app.ProcImage.ImageSource = [d '\proc\' procfiles(i,:)];            
            app.Fileindex.Value =i;
            loadcruisetrack(app)      
            
            evfiles = rawfiles(:,1:17);
            evfilelist = unique(evfiles,'rows');
            [p,~] =  size(evfilelist);
            num_evfiles = p;                        
            
            % display image filename
            i= app.Fileindex.Value - 1;
            if i>=1                
                app.RawImage.ImageSource = [d '\raw\' rawfiles(i,:)];                
                app.ProcImage.ImageSource = [d '\proc\' procfiles(i,:)];
                app.Fileindex.Value =i;
                
                evfile = rawfiles(i,1:17);      
                image_date = rawfiles(i,19:33);
                which_ev = find(~cellfun('isempty',strfind(cellstr(evfilelist),evfile)),1);                           
                app.ImageNameLabel.Text = sprintf('%s.ev. File %d of %d. Image time: %s',evfile,which_ev,num_evfiles,image_date); 
                
            else
                fprintf('at the start of the image set\n');
            end


        end

        % Value changed function: StartTime
        function StartTimeValueChanged(app, event)
            value = app.StartTime.Value;
            loadcruisetrack(app)
        end

        % Value changed function: SetendpointSlider
        function SetendpointSliderValueChanged(app, event)
            value = app.SetendpointSlider.Value;
            loadcruisetrack(app)
        end

        % Value changed function: SetfrequencyDropDown
        function SetfrequencyDropDownValueChanged(app, event)
            value = app.SetfrequencyDropDown.Value;
            
            value = app.ScrollimagesSlider.Value;                                             
            d = app.ImageLocation.Value;
            filetype = app.Setfiletype.Value;            
            freq = app.SetfrequencyDropDown.Value;
            freq = cell2mat(regexp(freq,'\d*','Match'));                        
            rawfiles = ls([d '\raw\*' freq  'k*.' filetype]); 
            procfiles = ls([d '\proc\*' freq  'k*.' filetype]);            
            [m,~]=size(rawfiles);            
            if isequal(m,0)
                msgbox(sprintf('No %s images for %s kHz',filetype,freq));
            else
                i = round(app.ScrollimagesSlider.Value);
                app.RawImage.ImageSource = [d '\raw\' rawfiles(i,:)];                
                app.ProcImage.ImageSource = [d '\proc\' procfiles(i,:)];                       
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EchogramTab = uitab(app.TabGroup);
            app.EchogramTab.Title = 'Echogram';
            app.ProcImage = uiimage(app.EchogramTab);
            app.BrowseImageLocatoin = uibutton(app.EchogramTab, 'push');
            app.BrowseImageLocatoin.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageLocatoinButtonPushed, true);
            app.BrowseImageLocatoin.Tooltip = {'Set locatoin of image directory where subfolders of raw and proc will exist'};
            app.BrowseImageLocatoin.Text = 'Set image directory';
            app.RawImage = uiimage(app.EchogramTab);
            app.ImageLocation = uieditfield(app.EchogramTab, 'text');
            app.OpenEvFileRaw = uibutton(app.EchogramTab, 'push');
            app.OpenEvFileRaw.ButtonPushedFcn = createCallbackFcn(app, @OpenEvFileRawButtonPushed, true);
            app.OpenEvFileRaw.Text = 'Open Ev file';
            app.Auto = uibutton(app.EchogramTab, 'push');
            app.Auto.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed2, true);
            app.Auto.Text = 'Auto view';
            app.Manual = uibutton(app.EchogramTab, 'push');
            app.Manual.ButtonPushedFcn = createCallbackFcn(app, @ManualButtonPushed, true);
            app.Manual.Text = 'Stop';
            app.BackButton = uibutton(app.EchogramTab, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.ForwardButton = uibutton(app.EchogramTab, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.ResetButton = uibutton(app.EchogramTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ImageNameLabel = uilabel(app.EchogramTab);
            app.ImageNameLabel.Text = 'Image Name';
            app.FiletypeLabel = uilabel(app.EchogramTab);
            app.FiletypeLabel.Text = 'File type';
            app.Setfiletype = uidropdown(app.EchogramTab);
            app.Setfiletype.Items = {'png', 'jpg'};
            app.Setfiletype.Value = 'png';
            app.EditFieldLabel = uilabel(app.EchogramTab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.Fileindex = uieditfield(app.EchogramTab, 'numeric');
            app.Fileindex.Value = 1;
            app.PlaybackspeedLabel = uilabel(app.EchogramTab);
            app.PlaybackspeedLabel.Text = {'Playback '; 'speed'};
            app.Playbackspeed = uislider(app.EchogramTab);
            app.Playbackspeed.Value = 50;
            app.SetfrequencyDropDownLabel = uilabel(app.EchogramTab);
            app.SetfrequencyDropDownLabel.Text = 'Set frequency';
            app.SetfrequencyDropDown = uidropdown(app.EchogramTab);
            app.SetfrequencyDropDown.Items = {'38 kHz', '18 kHz', '70 kHz', '120 kHz', '200 kHz'};
            app.SetfrequencyDropDown.ValueChangedFcn = createCallbackFcn(app, @SetfrequencyDropDownValueChanged, true);
            app.SetfrequencyDropDown.Value = '38 kHz';
            app.ScrollimagesSliderLabel = uilabel(app.EchogramTab);
            app.ScrollimagesSliderLabel.Text = 'Scroll images';
            app.ScrollimagesSlider = uislider(app.EchogramTab);
            app.ScrollimagesSlider.MajorTicks = [];
            app.ScrollimagesSlider.ValueChangedFcn = createCallbackFcn(app, @ScrollimagesSliderValueChanged, true);
            app.CruiseTrackTab = uitab(app.TabGroup);
            app.CruiseTrackTab.SizeChangedFcn = createCallbackFcn(app, @CruisetrackButtonPushed, true);
            app.CruiseTrackTab.Title = 'Cruise Track';
            app.CruiseTrackFig = uiaxes(app.CruiseTrackTab);
            title(app.CruiseTrackFig, 'Cruise track')
            xlabel(app.CruiseTrackFig, 'Longitude (deg)')
            ylabel(app.CruiseTrackFig, 'Latitude (deg)')
            app.SetstartpointLabel = uilabel(app.CruiseTrackTab);
            app.SetstartpointLabel.Text = 'Set start point';
            app.StartTime = uislider(app.CruiseTrackTab);
            app.StartTime.ValueChangedFcn = createCallbackFcn(app, @StartTimeValueChanged, true);
            app.SetendpointSliderLabel = uilabel(app.CruiseTrackTab);
            app.SetendpointSliderLabel.Text = 'Set end point';
            app.SetendpointSlider = uislider(app.CruiseTrackTab);
            app.SetendpointSlider.ValueChangedFcn = createCallbackFcn(app, @SetendpointSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BASOOP_viewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end